classdef GUIBiomecanica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        menAyuda            matlab.ui.container.Menu
        tabgAnalisis        matlab.ui.container.TabGroup
        tabTabla            matlab.ui.container.Tab
        tblDatos            matlab.ui.control.Table
        tabGrafica          matlab.ui.container.Tab
        drpArticulaciones   matlab.ui.control.DropDown
        axsAnalisis         matlab.ui.control.UIAxes
        Panel               matlab.ui.container.Panel
        lblMapaColor        matlab.ui.control.Label
        knbSup              matlab.ui.control.Knob
        knbInf              matlab.ui.control.Knob
        txtaMensajes        matlab.ui.control.TextArea
        Lamp                matlab.ui.control.Lamp
        IniciarSwitch       matlab.ui.control.Switch
        IniciarSwitchLabel  matlab.ui.control.Label
        axsPuntos           matlab.ui.control.UIAxes
        axsPosicion         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Valor1 = 0;
        Valor2 = 4096;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clearAllMemoizedCaches;
            app.knbInf.Value = app.Valor1;
            app.knbSup.Value = app.Valor2;
            app.Lamp.Color = [0.64,0.08,0.18];
            app.tblDatos.Data = zeros([8 2]);            
            app.axsPosicion.YLim = [0 380];
            app.axsPosicion.XLim = [0 512]; %514 368            
            app.txtaMensajes.Value = "Iniciar Programa";
            monitor = get(0,'ScreenSize');
            interfaz = app.UIFigure.Position;
            x = (monitor(3)/2)-(interfaz(3)/2);
            y = (monitor(4)/2)-(interfaz(4)/2);
            app.UIFigure.Position = [x y interfaz(3) interfaz(4)];
        end

        % Value changed function: IniciarSwitch
        function IniciarSwitchValueChanged(app, event)
            global depthVid datos depthMetaData
            value = app.IniciarSwitch.Value;
            x=0;
            if strcmp(value,'On')
                try
                    close all;
                    clc;
                    app.Lamp.Color = [0.00,1.00,0.00];
                
                    app.txtaMensajes.Value = "Reseteando imagen";
                    pause(3);
                    imaqreset; %Resetea el objeto de imagenes
                
                    app.txtaMensajes.Value = "Creando objeto de video";
                    pause(3);
                    depthVid = videoinput('kinect', 2);
                    %colorVid = videoinput('kinect', 1);
                
                    app.txtaMensajes.Value = "Configurando Frames";
                    pause(3);
                    %confuguracion del disparador                   
                    depthVid.Timeout = 200; %se asigna un tiempo de espera para toma de frames
                    triggerconfig(depthVid,'manual');
                    depthVid.FramesPerTrigger = 1;
                    depthVid.TriggerRepeat = inf;
                    set(getselectedsource(depthVid),'EnableBodyTracking','on');
                    
                    app.txtaMensajes.Value = "Iniciando Video";
                    pause(3);
                    %Inicio del video de profundidad
                    start(depthVid)
                    himg = figure; %crea un objeto figure   
                    %ejes = app.axsPosicion;
                    
                    SkeletonConnectionMap = [ 
                          [4 3];  % Cuello
                          [3 21]; % Cabeza
                          [21 2]; % Pierna derecha
                          [2 1];
                          [21 9];
                          [9 10];  % Cadera
                          [10 11];
                          [11 12]; % Pierna Izquierda
                          [12 24];
                          [12 25];
                          [21 5];  % Columna vertebral
                          [5 6];
                          [6 7];   % Mano izquierda
                          [7 8];
                          [8 22];
                          [8 23];
                          [1 17];
                          [17 18];
                          [18 19];  % Mano derecha
                          [19 20];
                          [1 13];
                          [13 14];
                          [14 15];
                          [15 16];
                        ];

             
                    app.txtaMensajes.Value = "Adquisición de Datos iniciada";
                    %ciclo para vizualizar los puntos en la figura
                    while ishandle(himg)
                        himg.Visible = "off";
                        trigger(depthVid);
                        [depthMap,~,depthMetaData] = getdata(depthVid);
                        %[image,imageMetaData] = getsnapshot(colorVid);                        
                        %anyBodiesTracked = any(depthMetaData.IsBodyTracked ~= 0);
                        trackedBodies = find(depthMetaData.IsBodyTracked);
                        nBodies = length(trackedBodies);
                        colors = ['r';'g';'b';'c';'y';'m'];
                        imshow(depthMap,[app.Valor1 app.Valor2],'Parent',app.axsPosicion);
                        %imaqmontage(depthMap, 'Parent', app.axsPosicion);                    
                        
                        %verifica si la suma del seguimiento del cuerpo es mayor a cero
                        if sum(depthMetaData.IsBodyTracked)>0
                            skeletonJoints = depthMetaData.DepthJointIndices(:,:,depthMetaData.IsBodyTracked);                                                       
                            plot(app.axsPosicion,skeletonJoints(:,1), skeletonJoints(:,2),'*r');                   

                            %hold(app.axsPuntos,'on');                            
                            for i = 1:24
                                for body = 1:nBodies                                    
                                    X1 = [skeletonJoints(SkeletonConnectionMap(i,1),1,body); skeletonJoints(SkeletonConnectionMap(i,2),1,body)];
                                    Y1 = [skeletonJoints(SkeletonConnectionMap(i,1),2,body), skeletonJoints(SkeletonConnectionMap(i,2),2,body)];                                    
                                    plot(app.axsPuntos,X1,Y1, 'LineWidth', 2, 'LineStyle', '-' , 'Marker', '+', 'Color', colors(body), 'MarkerEdgeColor','b');                                                                                                                                                                                                                                                         
                                end  
                                hold(app.axsPuntos,'on');                                
                            end
                            hold(app.axsPuntos,'off');

                            datos = [skeletonJoints(13:20,1) skeletonJoints(13:20,2)];
                            app.tblDatos.Data = datos;

                            value = app.drpArticulaciones.Value;
            
                            try
                                switch value
                                    %se grafica solo el punto Y para observar la altura de
                                    %cada ariticulación
                                    case 'Cadera'              
                                        hold(app.axsAnalisis,'off');
                                        plot(app.axsAnalisis,datos(1,2),1,'.','MarkerSize',20,'DisplayName','Izquierda');
                                        hold(app.axsAnalisis,'on');
                                        plot(app.axsAnalisis,datos(5,2),1,'.','MarkerSize',20,'DisplayName','Derecha');
                                        legend(app.axsAnalisis);
                                    case 'Rodilla'
                                        hold(app.axsAnalisis,'off');
                                        plot(app.axsAnalisis,datos(2,2),1,'.','MarkerSize',20,'DisplayName','Izquierda');
                                        hold(app.axsAnalisis,'on');
                                        plot(app.axsAnalisis,datos(6,2),1,'.','MarkerSize',20,'DisplayName','Derecha');
                                        legend(app.axsAnalisis);
                                    case 'Tobillo'
                                        hold(app.axsAnalisis,'off');
                                        plot(app.axsAnalisis,datos(3,2),1,'.','MarkerSize',20,'DisplayName','Izquierda');
                                        hold(app.axsAnalisis,'on');
                                        plot(app.axsAnalisis,datos(7,2),1,'.','MarkerSize',20,'DisplayName','Derecha');
                                        legend(app.axsAnalisis);
                                    case 'Pie'
                                        hold(app.axsAnalisis,'off');
                                        plot(app.axsAnalisis,datos(4,2),1,'.','MarkerSize',20,'DisplayName','Izquierda');
                                        hold(app.axsAnalisis,'on');
                                        plot(app.axsAnalisis,datos(8,2),1,'.','MarkerSize',20,'DisplayName','Derecha');
                                        legend(app.axsAnalisis);
                                end

                            catch ME
                                plot(app.axsAnalisis,0,0);
                            end
                            
                        else
                            imshow(depthMap,[app.Valor1 app.Valor2],'Parent',app.axsPosicion);
                            cla(app.axsPuntos);
                            %imaqmontage(depthMap, 'Parent', app.axsPosicion);
                        end%if-else
                        
                    end%while
                
                catch ME
                    
                    switch ME.identifier
                        case 'imaq:kinect:createDepthInstance'
                            app.txtaMensajes.Value = 'No es posible crear un objeto de video, por favor apage el programa';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'imaq:videoinput:noDevices'
                            app.txtaMensajes.Value = 'El dispositivo Kinect no se encuentra conectado.';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'MATLAB:nonExistentField'
                            app.txtaMensajes.Value = 'El seguimiento del cuerpo, no se puede realizar.';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'imaq:getdata:notRunning'
                            app.txtaMensajes.Value = 'No se puede mostrar video o no hay frames disponibles.';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'imaq:getdata:invalidOBJ'
                            app.txtaMensajes.Value = 'Se ha eliminado el video, programa finalizado.';
                        case 'imaq:getdata:timeout'
                            app.txtaMensajes.Value = 'El tiempo de espera esta agotado.';                        
                        otherwise
                            app.txtaMensajes.Value = 'Hay un error de ejucuación, reiniciar el programa';
                            app.Lamp.Color = [1.00,1.00,0.00];
                    end%switch
                    
                end%try-catch
                
                
            else
                app.Lamp.Color = [0.64,0.08,0.18];
                app.txtaMensajes.Value = "Programa Finalizado";                
                if depthVid ~= 0
                    stop(depthVid);                    
                    flushdata(depthVid);
                    delete(depthVid);
                else
                    clear;
                end                
                close all;                
            end%end if-else
        end

        % Menu selected function: menAyuda
        function menAyudaMenuSelected(app, event)
            
        end

        % Value changing function: knbInf
        function knbInfValueChanging(app, event)
            changingValue = event.Value;
            app.Valor1 = changingValue;
        end

        % Value changing function: knbSup
        function knbSupValueChanging(app, event)
            changingValue = event.Value;
            app.Valor2 = changingValue;
        end

        % Value changed function: drpArticulaciones
        function drpArticulacionesValueChanged(app, event)
            global datos
            value = app.drpArticulaciones.Value;
            
%             try
%                 switch value
%                     %se grafica solo el punto Y para observar la altura de
%                     %cada ariticulación
%                     case 'Cadera'              
%                         hold(app.axsAnalisis,'off');
%                         plot(app.axsAnalisis,x,datos(1,2),'.','MarkerSize',20,'DisplayName','Izquierda');
%                         hold(app.axsAnalisis,'on');
%                         plot(app.axsAnalisis,x,datos(5,2),'.','MarkerSize',20,'DisplayName','Derecha');
%                         legend(app.axsAnalisis);
%                     case 'Rodilla'
%                         hold(app.axsAnalisis,'off');
%                         plot(app.axsAnalisis,datos(2,2),'.','MarkerSize',20,'DisplayName','Izquierda');
%                         hold(app.axsAnalisis,'on');
%                         plot(app.axsAnalisis,datos(6,2),'.','MarkerSize',20,'DisplayName','Derecha');
%                         legend(app.axsAnalisis);
%                     case 'Tobillo'
%                         hold(app.axsAnalisis,'off');
%                         plot(app.axsAnalisis,datos(3,2),'.','MarkerSize',20,'DisplayName','Izquierda');
%                         hold(app.axsAnalisis,'on');
%                         plot(app.axsAnalisis,datos(7,2),'.','MarkerSize',20,'DisplayName','Derecha');
%                         legend(app.axsAnalisis);
%                     case 'Pie'
%                         hold(app.axsAnalisis,'off');
%                         plot(app.axsAnalisis,datos(4,2),'.','MarkerSize',20,'DisplayName','Izquierda');
%                         hold(app.axsAnalisis,'on');
%                         plot(app.axsAnalisis,datos(8,2),'.','MarkerSize',20,'DisplayName','Derecha');
%                         legend(app.axsAnalisis);
%                 end
% 
%             catch ME
%                 app.txtaMensajes.Value = 'No hay datos disponibles,iniciar el programa';
%                 app.Lamp.Color = [1.00,1.00,0.00];
%             end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.menAyuda = uimenu(app.UIFigure);
            app.menAyuda.MenuSelectedFcn = createCallbackFcn(app, @menAyudaMenuSelected, true);
            app.menAyuda.Text = 'Ayuda';
            app.axsPosicion = uiaxes(app.UIFigure);
            title(app.axsPosicion, 'Video')
            app.axsPosicion.XTick = [];
            app.axsPosicion.XTickLabel = '';
            app.axsPosicion.YTick = [];
            app.axsPosicion.LineStyleOrder = {'-'};
            app.axsPuntos = uiaxes(app.UIFigure);
            title(app.axsPuntos, 'Esqueleto')
            app.axsPuntos.YDir = 'reverse';
            app.Panel = uipanel(app.UIFigure);
            app.IniciarSwitchLabel = uilabel(app.Panel);
            app.IniciarSwitchLabel.Text = 'Iniciar';
            app.IniciarSwitch = uiswitch(app.Panel, 'slider');
            app.IniciarSwitch.ValueChangedFcn = createCallbackFcn(app, @IniciarSwitchValueChanged, true);
            app.Lamp = uilamp(app.Panel);
            app.txtaMensajes = uitextarea(app.Panel);
            app.txtaMensajes.Value = {''; ''};
            app.knbInf = uiknob(app.Panel, 'continuous');
            app.knbInf.Limits = [0 4096];
            app.knbInf.ValueChangingFcn = createCallbackFcn(app, @knbInfValueChanging, true);
            app.knbSup = uiknob(app.Panel, 'continuous');
            app.knbSup.Limits = [0 4096];
            app.knbSup.ValueChangingFcn = createCallbackFcn(app, @knbSupValueChanging, true);
            app.lblMapaColor = uilabel(app.Panel);
            app.lblMapaColor.Text = 'Mapa de Color [Valor1 Valor2]';
            app.tabgAnalisis = uitabgroup(app.UIFigure);
            app.tabTabla = uitab(app.tabgAnalisis);
            app.tabTabla.Title = 'Tabla';
            app.tblDatos = uitable(app.tabTabla);
            app.tblDatos.ColumnName = {'X'; 'Y'};
            app.tblDatos.RowName = {'Cadera izquierda'; 'Rodilla izquierda'; 'Tobillo izquierdo'; 'Pie izquierdo'; 'Cadera derecha'; 'Rodilla derecha'; 'Tobillo derecho'; 'Pie derecho'};
            app.tabGrafica = uitab(app.tabgAnalisis);
            app.tabGrafica.Title = 'Grafica';
            app.axsAnalisis = uiaxes(app.tabGrafica);
            ylabel(app.axsAnalisis, ' ')
            app.axsAnalisis.ZDir = 'reverse';
            app.drpArticulaciones = uidropdown(app.tabGrafica);
            app.drpArticulaciones.Items = {'Cadera', 'Rodilla', 'Tobillo', 'Pie'};
            app.drpArticulaciones.ValueChangedFcn = createCallbackFcn(app, @drpArticulacionesValueChanged, true);
            app.drpArticulaciones.Value = 'Cadera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIBiomecanica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end